classdef FinalProjectGUI_basicMovement < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        MapPanel                   matlab.ui.container.Panel
        Map                        matlab.ui.control.Table
        RobotStatusLabel           matlab.ui.control.Label
        TopologicalPathEntryPanel  matlab.ui.container.Panel
        SendStartEndButton         matlab.ui.control.Button
        SendPathButton             matlab.ui.control.Button
        StartEndLabel              matlab.ui.control.Label
        EndLabel                   matlab.ui.control.Label
        StartLabel                 matlab.ui.control.Label
        SetEndEditField            matlab.ui.control.EditField
        SetEndxyEditFieldLabel     matlab.ui.control.Label
        SetStartEditField          matlab.ui.control.EditField
        SetStartxyEditFieldLabel   matlab.ui.control.Label
        EnterPathEditField         matlab.ui.control.EditField
        EnterPathLabel             matlab.ui.control.Label
        pathDisplayLabel           matlab.ui.control.Label
        CurrentpathLabel           matlab.ui.control.Label
        MotorControlPanel          matlab.ui.container.Panel
        RightMotorSlider           matlab.ui.control.Slider
        RightLabel                 matlab.ui.control.Label
        LeftMotorSlider            matlab.ui.control.Slider
        LeftLabel                  matlab.ui.control.Label
        SensorDataPanel            matlab.ui.container.Panel
        Image                      matlab.ui.control.Image
        backLidarLabel             matlab.ui.control.Label
        frontLidarLabel            matlab.ui.control.Label
        rightLidarLabel            matlab.ui.control.Label
        leftLidarLabel             matlab.ui.control.Label
        LEDControlPanel            matlab.ui.container.Panel
        YellowLEDLamp              matlab.ui.control.Lamp
        YellowLEDLampLabel         matlab.ui.control.Label
        RedLEDLamp                 matlab.ui.control.Lamp
        RedLEDLampLabel            matlab.ui.control.Label
        GreenLEDLamp               matlab.ui.control.Lamp
        GreenLEDLampLabel          matlab.ui.control.Label
        YellowSwitch               matlab.ui.control.Switch
        YellowSwitchLabel          matlab.ui.control.Label
        RedSwitch                  matlab.ui.control.Switch
        RedSwitchLabel             matlab.ui.control.Label
        GreenSwitch                matlab.ui.control.Switch
        GreenSwitchLabel           matlab.ui.control.Label
    end

    
    properties (Access = private)
        mqClient % MQTT client object
        currentPath % current path
        distanceGrid % Store the distance grid data
    end
    
    methods (Access = private)
        % Callback for sensor data from Arduino
        function handleSensorData(app, msg)
            % Parse CSV: frontL,backL,leftL,rightL,frontLS,frontRS,backLS,backRS
            values = str2double(split(msg, ','));
            
            if length(values) == 8
                % Store in app properties or update GUI
                % Example: app.FrontLidarLabel.Text = sprintf('Front: %d', values(1));
                
                % Debug output
                fprintf('Sensors - F:%d B:%d L:%d R:%d FLS:%d FRS:%d BLS:%d BRS:%d\n', ...
                    values(1), values(2), values(3), values(4), ...
                    values(5), values(6), values(7), values(8));
            end

            % Assign lidar values
            frontL = values(1);
            backL  = values(2);
            leftL  = values(3);
            rightL = values(4);
            
            % -------- UPDATE GUI LABELS --------
            app.frontLidarLabel.Text = sprintf("Front\n%d", backL);
            app.backLidarLabel.Text  = sprintf("Back\n%d", frontL);
            app.leftLidarLabel.Text  = sprintf("Left\n%d", rightL);
            app.rightLidarLabel.Text = sprintf("Right\n%d", leftL);

            % Optional: simple visual warning
            thresh = 20; % cm (adjust to your robot)
        
            if frontL < thresh
                app.frontLidarLabel.FontColor = [1 0 0];
            else
                app.frontLidarLabel.FontColor = [0 0 0];
            end
        
            if leftL < thresh
                app.leftLidarLabel.FontColor = [1 0 0];
            else
                app.leftLidarLabel.FontColor = [0 0 0];
            end
        
            if rightL < thresh
                app.rightLidarLabel.FontColor = [1 0 0];
            else
                app.rightLidarLabel.FontColor = [0 0 0];
            end
        
            if backL < thresh
                app.backLidarLabel.FontColor = [1 0 0];
            else
                app.backLidarLabel.FontColor = [0 0 0];
            end
        end    
        % Handler for messages from Arduino
        function handleArduinoMessage(app, msg)
            timestamp = datestr(now, 'HH:MM:SS');
            fprintf('[%s] << ARDUINO: %s\n', timestamp, msg);
            
            % Check if message starts with "TEXT:"
            if startsWith(msg, 'TEXT:')
                % Extract the text after "TEXT:"
                displayText = extractAfter(msg, 'TEXT:');
                
                % Update the Robot Status Label
                app.RobotStatusLabel.Text = displayText;
            else
                % For other messages (like LED confirmations), just display them
                % app.RobotStatusLabel.Text = msg;
            end
        end

        function handleGridData(app, msg)
            % Parse: GRID:rows,cols:data
            parts = split(extractAfter(msg, 'GRID:'), ':');
            
            % Get dimensions
            dims = str2double(split(parts{1}, ','));
            rows = dims(1);
            cols = dims(2);
            
            % Parse grid data
            % Split by semicolon for rows, then comma for columns
            rowStrings = split(parts{2}, ';');
            gridData = zeros(rows, cols);
            
            for r = 1:rows
                colData = str2double(split(rowStrings{r}, ','));
                gridData(r, :) = colData;
            end
            
            % Store in app property
            app.distanceGrid = gridData;
            
            % Format for display
            displayData = cell(rows, cols);
            for r = 1:rows
                for c = 1:cols
                    val = gridData(r, c);
                    if val == -2
                        displayData{r, c} = '##';  % Obstacle
                    elseif val == -1
                        displayData{r, c} = '??';  % Unknown
                    else
                        displayData{r, c} = num2str(val);
                    end
                end
            end
            
            % Update table
            app.Map.Data = displayData;
            
            fprintf('Grid received and displayed: %dx%d\n', rows, cols);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Setup MQTT connection
            app.mqClient = mqttclient("tcp://broker.hivemq.com");
            % Direct anonymous function callbacks
            subscribe(app.mqClient, 'ece445/parkj10/to_arduino', ...
                'Callback', @(~, msg) fprintf('[%s] >> SENT: %s\n', datestr(now, 'HH:MM:SS'), char(msg)));
            
            subscribe(app.mqClient, 'ece445/parkj10/to_matlab', ...
                'Callback', @(~, msg) app.handleArduinoMessage(char(msg)));
            
            subscribe(app.mqClient, 'ece445/parkj10/sensors_to_matlab', ...
                'Callback', @(~, msg) app.handleSensorData(char(msg)));

            pause(1);
        end

        % Value changed function: GreenSwitch
        function GreenSwitchValueChanged(app, event)
            value = app.GreenSwitch.Value;
            
        end

        % Value changed function: YellowSwitch
        function YellowSwitchValueChanged(app, event)
            value = app.YellowSwitch.Value;
            
        end

        % Value changed function: RedSwitch
        function RedSwitchValueChanged(app, event)
            value = app.RedSwitch.Value;
            if strcmp(value, 'On')
                write(app.mqClient, 'ece445/parkj10/to_arduino', 'RED_ON');
            else
                write(app.mqClient, 'ece445/parkj10/to_arduino', 'RED_OFF');
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Cleanup MQTT connection
            if ~isempty(app.mqClient)
                unsubscribe(app.mqClient, 'Topic', 'ece445/parkj10/to_arduino');
                unsubscribe(app.mqClient, 'Topic', 'ece445/parkj10/to_matlab');
                
                % Clear the client
                clear app.mqClient;  % Note: app.mqClient, not just mqClient
                
                fprintf('Disconnected.\n');
            end
            
            delete(app);
        end

        % Value changed function: EnterPathEditField
        function EnterPathEditFieldValueChanged(app, event)
            app.currentPath = upper(strtrim(app.EnterPathEditField.Value));
    
            % Check if path is empty
            if isempty(app.currentPath)
                app.pathDisplayLabel.Text = '[path]';
                return;
            end
            
            % Validation 1: Only allow S L R F T characters
            if ~isempty(regexp(app.currentPath, '[^SLRFT]', 'once'))
                uialert(app.UIFigure, ...
                    'Path may only contain S, L, R, F, T', ...
                    'Invalid Path');
                app.EnterPathEditField.Value = ''; % Clear the field
                app.pathDisplayLabel.Text = '[path]';
                return;
            end
            
            % Validation 2: Must start with S
            if app.currentPath(1) ~= 'S'
                uialert(app.UIFigure, ...
                    'Path must start with S', ...
                    'Invalid Path');
                app.EnterPathEditField.Value = ''; % Clear the field
                app.pathDisplayLabel.Text = '[path]';
                return;
            end
            
            % Validation 3: Must end with T
            if app.currentPath(end) ~= 'T'
                uialert(app.UIFigure, ...
                    'Path must end with T', ...
                    'Invalid Path');
                app.EnterPathEditField.Value = ''; % Clear the field
                app.pathDisplayLabel.Text = '[path]';
                return;
            end
            
            % If all validations pass, update display
            app.pathDisplayLabel.Text = app.currentPath;
        end

        % Button pushed function: SendPathButton
        function SendPathButtonPushed(app, event)
            if strlength(app.currentPath) == 0
                uialert(app.UIFigure, 'No path entered!', 'Error');
                return;
            end
        
            msg = "PATH:" + app.currentPath;
            write(app.mqClient, 'ece445/parkj10/to_arduino', msg);
        
            fprintf('[MATLAB] Sent: %s\n', msg);
            
            % Clear the text box and reset display
            app.EnterPathEditField.Value = '';
            % app.pathDisplayLabel.Text = '[path]';
            % app.currentPath = '';  % Also clear the stored path
        end

        % Button pushed function: SendStartEndButton
        function SendStartEndButtonPushed(app, event)
            % Get the start and end values
            startValue = strtrim(app.SetStartEditField.Value);
            endValue = strtrim(app.SetEndEditField.Value);
            
            % Validate that both fields are filled
            if isempty(startValue) || isempty(endValue)
                uialert(app.UIFigure, 'Both Start and End locations must be entered!', 'Missing Data');
                return;
            end
            
            % Optional: Validate format (x,y) - assumes format like "1,2" or "0,0"
            startParts = split(startValue, ',');
            endParts = split(endValue, ',');
            
            if length(startParts) ~= 2 || length(endParts) ~= 2
                uialert(app.UIFigure, 'Start and End must be in format: x,y', 'Invalid Format');
                return;
            end
            
            % Check if values are numeric
            startX = str2double(startParts{1});
            startY = str2double(startParts{2});
            endX = str2double(endParts{1});
            endY = str2double(endParts{2});
            
            if isnan(startX) || isnan(startY) || isnan(endX) || isnan(endY)
                uialert(app.UIFigure, 'Coordinates must be numeric values', 'Invalid Format');
                return;
            end
            
            % Send start location
            startMsg = sprintf('START:%s', startValue);
            write(app.mqClient, 'ece445/parkj10/to_arduino', startMsg);
            fprintf('[MATLAB] Sent: %s\n', startMsg);
            
            % Send end location
            endMsg = sprintf('END:%s', endValue);
            write(app.mqClient, 'ece445/parkj10/to_arduino', endMsg);
            fprintf('[MATLAB] Sent: %s\n', endMsg);
            
            % Update the Start/End display labels
            app.StartLabel.Text = startValue;
            app.EndLabel.Text = endValue;
            
            app.SetStartEditField.Value = '';
            app.SetEndEditField.Value = '';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.LEDControlPanel = uipanel(app.UIFigure);
            app.LEDControlPanel.Title = 'LED Control Panel';
            app.GreenSwitchLabel = uilabel(app.LEDControlPanel);
            app.GreenSwitchLabel.Text = 'Green';
            app.GreenSwitch = uiswitch(app.LEDControlPanel, 'slider');
            app.GreenSwitch.ValueChangedFcn = createCallbackFcn(app, @GreenSwitchValueChanged, true);
            app.RedSwitchLabel = uilabel(app.LEDControlPanel);
            app.RedSwitchLabel.Text = 'Red';
            app.RedSwitch = uiswitch(app.LEDControlPanel, 'slider');
            app.RedSwitch.ValueChangedFcn = createCallbackFcn(app, @RedSwitchValueChanged, true);
            app.YellowSwitchLabel = uilabel(app.LEDControlPanel);
            app.YellowSwitchLabel.Text = 'Yellow';
            app.YellowSwitch = uiswitch(app.LEDControlPanel, 'slider');
            app.YellowSwitch.ValueChangedFcn = createCallbackFcn(app, @YellowSwitchValueChanged, true);
            app.GreenLEDLampLabel = uilabel(app.LEDControlPanel);
            app.GreenLEDLampLabel.Text = 'Green LED';
            app.GreenLEDLamp = uilamp(app.LEDControlPanel);
            app.RedLEDLampLabel = uilabel(app.LEDControlPanel);
            app.RedLEDLampLabel.Text = 'Red LED';
            app.RedLEDLamp = uilamp(app.LEDControlPanel);
            app.YellowLEDLampLabel = uilabel(app.LEDControlPanel);
            app.YellowLEDLampLabel.Text = 'Yellow LED';
            app.YellowLEDLamp = uilamp(app.LEDControlPanel);
            app.SensorDataPanel = uipanel(app.UIFigure);
            app.SensorDataPanel.Title = 'Sensor Data';
            app.leftLidarLabel = uilabel(app.SensorDataPanel);
            app.rightLidarLabel = uilabel(app.SensorDataPanel);
            app.frontLidarLabel = uilabel(app.SensorDataPanel);
            app.backLidarLabel = uilabel(app.SensorDataPanel);
            app.Image = uiimage(app.SensorDataPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, '1337116-20240924_A_JPG Output.png');
            app.MotorControlPanel = uipanel(app.UIFigure);
            app.MotorControlPanel.Title = 'Motor Control';
            app.LeftLabel = uilabel(app.MotorControlPanel);
            app.LeftLabel.Text = 'Left %';
            app.LeftMotorSlider = uislider(app.MotorControlPanel);
            app.LeftMotorSlider.Limits = [-50 50];
            app.RightLabel = uilabel(app.MotorControlPanel);
            app.RightLabel.Text = 'Right %';
            app.RightMotorSlider = uislider(app.MotorControlPanel);
            app.RightMotorSlider.Limits = [-50 50];
            app.TopologicalPathEntryPanel = uipanel(app.UIFigure);
            app.TopologicalPathEntryPanel.Title = 'Topological Path Entry';
            app.CurrentpathLabel = uilabel(app.TopologicalPathEntryPanel);
            app.CurrentpathLabel.Text = 'Current path:';
            app.pathDisplayLabel = uilabel(app.TopologicalPathEntryPanel);
            app.pathDisplayLabel.Text = '[path]';
            app.EnterPathLabel = uilabel(app.TopologicalPathEntryPanel);
            app.EnterPathLabel.Text = 'Enter Path:';
            app.EnterPathEditField = uieditfield(app.TopologicalPathEntryPanel, 'text');
            app.EnterPathEditField.ValueChangedFcn = createCallbackFcn(app, @EnterPathEditFieldValueChanged, true);
            app.SetStartxyEditFieldLabel = uilabel(app.TopologicalPathEntryPanel);
            app.SetStartxyEditFieldLabel.Text = 'Set Start (x,y)';
            app.SetStartEditField = uieditfield(app.TopologicalPathEntryPanel, 'text');
            app.SetEndxyEditFieldLabel = uilabel(app.TopologicalPathEntryPanel);
            app.SetEndxyEditFieldLabel.Text = 'Set End (x,y)';
            app.SetEndEditField = uieditfield(app.TopologicalPathEntryPanel, 'text');
            app.StartLabel = uilabel(app.TopologicalPathEntryPanel);
            app.StartLabel.Text = '[Start]';
            app.EndLabel = uilabel(app.TopologicalPathEntryPanel);
            app.EndLabel.Text = '[End]';
            app.StartEndLabel = uilabel(app.TopologicalPathEntryPanel);
            app.StartEndLabel.Text = 'Start/End:';
            app.SendPathButton = uibutton(app.TopologicalPathEntryPanel, 'push');
            app.SendPathButton.ButtonPushedFcn = createCallbackFcn(app, @SendPathButtonPushed, true);
            app.SendPathButton.Text = {'Send'; 'Path'};
            app.SendStartEndButton = uibutton(app.TopologicalPathEntryPanel, 'push');
            app.SendStartEndButton.ButtonPushedFcn = createCallbackFcn(app, @SendStartEndButtonPushed, true);
            app.SendStartEndButton.Text = {'Send'; 'Start &'; 'End'};
            app.RobotStatusLabel = uilabel(app.UIFigure);
            app.RobotStatusLabel.Text = '[Robot Status]';
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map Panel';
            app.Map = uitable(app.MapPanel);
            app.Map.ColumnName = {'1'; '2'; '3'; '4'};
            app.Map.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalProjectGUI_basicMovement

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end